classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        DescriptionGame             matlab.ui.control.TextArea
        DescriptionTextArea_2Label  matlab.ui.control.Label
        Label_10                    matlab.ui.control.Label
        Label_9                     matlab.ui.control.Label
        Label_8                     matlab.ui.control.Label
        Label_7                     matlab.ui.control.Label
        Label_6                     matlab.ui.control.Label
        Label_5                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label_1                     matlab.ui.control.Label
        AddnewWordButton            matlab.ui.control.Button
        DescriptionNewWord          matlab.ui.control.TextArea
        DescriptionTextAreaLabel    matlab.ui.control.Label
        WordEditField               matlab.ui.control.EditField
        WordEditFieldLabel          matlab.ui.control.Label
        NewGameButton               matlab.ui.control.Button
        YButton                     matlab.ui.control.Button
        ZButton                     matlab.ui.control.Button
        XButton                     matlab.ui.control.Button
        VButton                     matlab.ui.control.Button
        TButton                     matlab.ui.control.Button
        RButton                     matlab.ui.control.Button
        PButton                     matlab.ui.control.Button
        NButton                     matlab.ui.control.Button
        LButton                     matlab.ui.control.Button
        JButton                     matlab.ui.control.Button
        HButton                     matlab.ui.control.Button
        FButton                     matlab.ui.control.Button
        DButton                     matlab.ui.control.Button
        BButton                     matlab.ui.control.Button
        QButton                     matlab.ui.control.Button
        SButton                     matlab.ui.control.Button
        UButton                     matlab.ui.control.Button
        WButton                     matlab.ui.control.Button
        IButton                     matlab.ui.control.Button
        KButton                     matlab.ui.control.Button
        MButton                     matlab.ui.control.Button
        OButton                     matlab.ui.control.Button
        GButton                     matlab.ui.control.Button
        EButton                     matlab.ui.control.Button
        CButton                     matlab.ui.control.Button
        AButton                     matlab.ui.control.Button
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        Dict
        Ids
        Words
        Descriptions
        gameWord
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Read data from xml file
            app.Dict = readstruct("dict.xml","FileType","xml");
            app.Ids = [app.Dict.item.idAttribute];
            app.Words = [app.Dict.item.word];
            app.Descriptions = [app.Dict.item.description];
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            dicLen = size(app.Words);
            len = dicLen(2);
            randIndex = randi([1 len],1);
            app.gameWord = app.Words(randIndex);
            wordlen = strlength(app.gameWord);
            for index = 1:10
               app.(sprintf('Label_%d',index)).Text = '';
            end
            for index = 1:wordlen
               app.(sprintf('Label_%d',index)).Text = '_';
            end
            app.DescriptionGame.Value = app.Descriptions(randIndex);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, '2.png');
            app.AButton = uibutton(app.UIFigure, 'push');
            app.AButton.Text = 'A';
            app.CButton = uibutton(app.UIFigure, 'push');
            app.CButton.Text = 'C';
            app.EButton = uibutton(app.UIFigure, 'push');
            app.EButton.Text = 'E';
            app.GButton = uibutton(app.UIFigure, 'push');
            app.GButton.Text = 'G';
            app.OButton = uibutton(app.UIFigure, 'push');
            app.OButton.Text = 'O';
            app.MButton = uibutton(app.UIFigure, 'push');
            app.MButton.Text = 'M';
            app.KButton = uibutton(app.UIFigure, 'push');
            app.KButton.Text = 'K';
            app.IButton = uibutton(app.UIFigure, 'push');
            app.IButton.Text = 'I';
            app.WButton = uibutton(app.UIFigure, 'push');
            app.WButton.Text = 'W';
            app.UButton = uibutton(app.UIFigure, 'push');
            app.UButton.Text = 'U';
            app.SButton = uibutton(app.UIFigure, 'push');
            app.SButton.Text = 'S';
            app.QButton = uibutton(app.UIFigure, 'push');
            app.QButton.Text = 'Q';
            app.BButton = uibutton(app.UIFigure, 'push');
            app.BButton.Text = 'B';
            app.DButton = uibutton(app.UIFigure, 'push');
            app.DButton.Text = 'D';
            app.FButton = uibutton(app.UIFigure, 'push');
            app.FButton.Text = 'F';
            app.HButton = uibutton(app.UIFigure, 'push');
            app.HButton.Text = 'H';
            app.JButton = uibutton(app.UIFigure, 'push');
            app.JButton.Text = 'J';
            app.LButton = uibutton(app.UIFigure, 'push');
            app.LButton.Text = 'L';
            app.NButton = uibutton(app.UIFigure, 'push');
            app.NButton.Text = 'N';
            app.PButton = uibutton(app.UIFigure, 'push');
            app.PButton.Text = 'P';
            app.RButton = uibutton(app.UIFigure, 'push');
            app.RButton.Text = 'R';
            app.TButton = uibutton(app.UIFigure, 'push');
            app.TButton.Text = 'T';
            app.VButton = uibutton(app.UIFigure, 'push');
            app.VButton.Text = 'V';
            app.XButton = uibutton(app.UIFigure, 'push');
            app.XButton.Text = 'X';
            app.ZButton = uibutton(app.UIFigure, 'push');
            app.ZButton.Text = 'Z';
            app.YButton = uibutton(app.UIFigure, 'push');
            app.YButton.Text = 'Y';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.WordEditFieldLabel = uilabel(app.UIFigure);
            app.WordEditFieldLabel.Text = 'Word';
            app.WordEditField = uieditfield(app.UIFigure, 'text');
            app.DescriptionTextAreaLabel = uilabel(app.UIFigure);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionNewWord = uitextarea(app.UIFigure);
            app.AddnewWordButton = uibutton(app.UIFigure, 'push');
            app.AddnewWordButton.Text = 'Add new Word';
            app.Label_1 = uilabel(app.UIFigure);
            app.Label_1.Text = '';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '';
            app.DescriptionTextArea_2Label = uilabel(app.UIFigure);
            app.DescriptionTextArea_2Label.Text = 'Description';
            app.DescriptionGame = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end