classdef CaletamientoGlobal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ColorDropDown              matlab.ui.control.DropDown
        ColorDropDownLabel         matlab.ui.control.Label
        EndYearDropDown            matlab.ui.control.DropDown
        EndYearDropDownLabel       matlab.ui.control.Label
        StartYearDropDown          matlab.ui.control.DropDown
        StartYearDropDownLabel     matlab.ui.control.Label
        TemperatureEditField       matlab.ui.control.NumericEditField
        TemperatureEditFieldLabel  matlab.ui.control.Label
        UpdateButton               matlab.ui.control.Button
        YearDropDown               matlab.ui.control.DropDown
        YearDropDownLabel          matlab.ui.control.Label
        GraphRangeButton           matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Temperature % Description
        Years
        Color
        Code
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.Color = app.ColorDropDown.Value;
        data = readtable('temperatura_anios.csv');
        app.Temperature = data.Var2 ;
        app.Years = data.Var1;
        app.YearDropDown.Items = string(app.Years);
        app.StartYearDropDown.Items = string(app.Years);
        app.EndYearDropDown.Items = string(app.Years(2:end));
        end

        % Value changed function: StartYearDropDown
        function StartYearDropDownValueChanged(app, event)
            value = app.StartYearDropDown.Value;
            StartIndex = find(app.Years == str2num(value)); %#ok<ST2NM>
            app.EndYearDropDown.Items = string(app.Years(StartIndex+1:end));
        end

        % Button pushed function: GraphRangeButton
        function GraphRangeButtonPushed(app, event)
            startYearIndex = find(app.Years == str2num(app.StartYearDropDown.Value)); %#ok<ST2NM> 
            endYearIndex = find(app.Years == str2num(app.EndYearDropDown.Value)); %#ok<ST2NM> 
            years = [app.Years(startYearIndex:endYearIndex)];
            temps = [app.Temperature(startYearIndex:endYearIndex)];
            plot(app.UIAxes,[years],[temps],'color',app.Color);
        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)
            ind = find(app.Years == str2num(app.YearDropDown.Value)); %#ok<ST2NM> 
            app.Temperature(ind) = app.TemperatureEditField.Value;
            startYearIndex = find(app.Years == str2num(app.StartYearDropDown.Value)); %#ok<ST2NM> 
            endYearIndex = find(app.Years == str2num(app.EndYearDropDown.Value)); %#ok<ST2NM> 
            years = [app.Years(startYearIndex:endYearIndex)];
            temps = [app.Temperature(startYearIndex:endYearIndex)];
             plot(app.UIAxes,[years],[temps],'color',app.Color);
        end

        % Value changed function: YearDropDown
        function YearDropDownValueChanged(app, event)
            value = app.YearDropDown.Value;
            ind = find(app.Years == str2num(value)); %#ok<ST2NM>
            app.TemperatureEditField.Value = app.Temperature(ind);
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            app.Color = value;
            startYearIndex = find(app.Years == str2num(app.StartYearDropDown.Value)); %#ok<ST2NM> 
            endYearIndex = find(app.Years == str2num(app.EndYearDropDown.Value)); %#ok<ST2NM> 
            years = [app.Years(startYearIndex:endYearIndex)];
            temps = [app.Temperature(startYearIndex:endYearIndex)];
            plot(app.UIAxes,[years],[temps],'color',app.Color);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Year')
            ylabel(app.UIAxes, {'Temperature'; ''})
            zlabel(app.UIAxes, 'Z')
            app.GraphRangeButton = uibutton(app.UIFigure, 'push');
            app.GraphRangeButton.ButtonPushedFcn = createCallbackFcn(app, @GraphRangeButtonPushed, true);
            app.GraphRangeButton.Text = 'Graph Range';
            app.YearDropDownLabel = uilabel(app.UIFigure);
            app.YearDropDownLabel.Text = 'Year';
            app.YearDropDown = uidropdown(app.UIFigure);
            app.YearDropDown.ValueChangedFcn = createCallbackFcn(app, @YearDropDownValueChanged, true);
            app.UpdateButton = uibutton(app.UIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.TemperatureEditFieldLabel = uilabel(app.UIFigure);
            app.TemperatureEditFieldLabel.Text = {'Temperature'; ''};
            app.TemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartYearDropDownLabel = uilabel(app.UIFigure);
            app.StartYearDropDownLabel.Text = 'Start Year';
            app.StartYearDropDown = uidropdown(app.UIFigure);
            app.StartYearDropDown.ValueChangedFcn = createCallbackFcn(app, @StartYearDropDownValueChanged, true);
            app.EndYearDropDownLabel = uilabel(app.UIFigure);
            app.EndYearDropDownLabel.Text = 'End Year';
            app.EndYearDropDown = uidropdown(app.UIFigure);
            app.ColorDropDownLabel = uilabel(app.UIFigure);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.UIFigure);
            app.ColorDropDown.Items = {'red', 'blue', 'green', 'cyan'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'red';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CaletamientoGlobal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end