classdef GestionDeEquiposBiomedicos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ConsultarporIDDropDown       matlab.ui.control.DropDown
        ConsultarporIDDropDownLabel  matlab.ui.control.Label
        ActualizarEquipoButton       matlab.ui.control.Button
        AgregarNuevoEquipoButton     matlab.ui.control.Button
        UbicacionEditField           matlab.ui.control.EditField
        UbicacionEditFieldLabel      matlab.ui.control.Label
        ModeloEditField              matlab.ui.control.EditField
        ModeloEditFieldLabel         matlab.ui.control.Label
        NombreEditField              matlab.ui.control.EditField
        NombreEditFieldLabel         matlab.ui.control.Label
    end

    
    properties (Access = private)
        Equipos
        Nombres
        Modelos
        Ubicaciones
        Ids
    end
    
    methods (Access = private)
        
        function leerEquipos(app)
            app.Equipos = readstruct("equipos.xml","FileType","xml");
            app.Ids = [app.Equipos.item.idAttribute];
            app.Nombres = [app.Equipos.item.Nombre];
            app.Modelos = [app.Equipos.item.Modelo];
            app.Ubicaciones = [app.Equipos.item.Ubicacion];
            app.ConsultarporIDDropDown.Items = string(app.Ids);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            leerEquipos(app);
        end

        % Button pushed function: AgregarNuevoEquipoButton
        function AgregarNuevoEquipoButtonPushed(app, event)
            nombre = app.NombreEditField.Value;
            modelo = app.ModeloEditField.Value;
            ubicacion = app.UbicacionEditField.Value;
                            
            if strlength(nombre) > 2 && strlength(modelo) > 2
                items = [];
                for index = 1:length(app.Ids)
                    item = struct( ...
                    "idAttribute", ...
                    index, ...
                    'Nombre', ...
                    app.Nombres(index), ...
                    'Modelo', ...
                    app.Modelos(index), ...
                    'Ubicacion', ...
                    app.Ubicaciones(index));

                    items = [items item]; %#ok<AGROW> 
                end


                item = struct( ...
                    "idAttribute", ...
                    length(app.Ids)+1, ...
                    'Nombre', ...
                    nombre, ...
                    'Modelo', ...
                    modelo, ...
                    'Ubicacion', ...
                    ubicacion);

                    items = [items item];


                S = struct( ...
                    'item',items);
                writestruct(S,"equipos.xml");

                leerEquipos(app);
                app.ConsultarporIDDropDown.Value = string(length(app.Ids));
                uialert(app.UIFigure,'Nuevo Equipo Agregado','Equipo agregado');
            else
                uialert(app.UIFigure,'Los campos deben ser diligenciados','Equipo No agregado');
            end
        end

        % Button pushed function: ActualizarEquipoButton
        function ActualizarEquipoButtonPushed(app, event)
            nombre = app.NombreEditField.Value;
            modelo = app.ModeloEditField.Value;
            ubicacion = app.UbicacionEditField.Value;
                            
            if strlength(nombre) > 2 && strlength(modelo) > 2
                items = [];
                for index = 1:length(app.Ids)
                    if index == str2num(app.ConsultarporIDDropDown.Value) %#ok<ST2NM> 
                        item = struct( ...
                        "idAttribute", ...
                        index, ...
                        'Nombre', ...
                        nombre, ...
                        'Modelo', ...
                        modelo, ...
                        'Ubicacion', ...
                        ubicacion);
                        
                        items = [items item]; %#ok<AGROW> 
                    else
                        item = struct( ...
                        "idAttribute", ...
                        index, ...
                        'Nombre', ...
                        app.Nombres(index), ...
                        'Modelo', ...
                        app.Modelos(index), ...
                        'Ubicacion', ...
                        app.Ubicaciones(index));

                        items = [items item]; %#ok<AGROW> 
                    end
                end
                
                S = struct( ...
                    'item',items);
                writestruct(S,"equipos.xml");

                leerEquipos(app);
                app.ConsultarporIDDropDown.Value = string(length(app.Ids));
                uialert(app.UIFigure,'Equipo Editado','Equipo editado');
            else
                uialert(app.UIFigure,'Los campos deben ser diligenciados','Equipo No agregado');
            end
        end

        % Value changed function: ConsultarporIDDropDown
        function ConsultarporIDDropDownValueChanged(app, event)
            value = app.ConsultarporIDDropDown.Value;
            app.NombreEditField.Value = app.Nombres(str2num(value)); %#ok<ST2NM> 
            app.ModeloEditField.Value = string(app.Modelos(str2num(value))); %#ok<ST2NM> 
            app.UbicacionEditField.Value = app.Ubicaciones(str2num(value)); %#ok<ST2NM> 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NombreEditFieldLabel = uilabel(app.UIFigure);
            app.NombreEditFieldLabel.Text = 'Nombre';
            app.NombreEditField = uieditfield(app.UIFigure, 'text');
            app.ModeloEditFieldLabel = uilabel(app.UIFigure);
            app.ModeloEditFieldLabel.Text = 'Modelo';
            app.ModeloEditField = uieditfield(app.UIFigure, 'text');
            app.UbicacionEditFieldLabel = uilabel(app.UIFigure);
            app.UbicacionEditFieldLabel.Text = 'Ubicacion';
            app.UbicacionEditField = uieditfield(app.UIFigure, 'text');
            app.AgregarNuevoEquipoButton = uibutton(app.UIFigure, 'push');
            app.AgregarNuevoEquipoButton.ButtonPushedFcn = createCallbackFcn(app, @AgregarNuevoEquipoButtonPushed, true);
            app.AgregarNuevoEquipoButton.Text = 'Agregar Nuevo Equipo';
            app.ActualizarEquipoButton = uibutton(app.UIFigure, 'push');
            app.ActualizarEquipoButton.ButtonPushedFcn = createCallbackFcn(app, @ActualizarEquipoButtonPushed, true);
            app.ActualizarEquipoButton.Text = 'Actualizar Equipo';
            app.ConsultarporIDDropDownLabel = uilabel(app.UIFigure);
            app.ConsultarporIDDropDownLabel.Text = 'Consultar por ID';
            app.ConsultarporIDDropDown = uidropdown(app.UIFigure);
            app.ConsultarporIDDropDown.ValueChangedFcn = createCallbackFcn(app, @ConsultarporIDDropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GestionDeEquiposBiomedicos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end